classdef TolStack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CS2Label                      matlab.ui.control.Label
        CS1Label                      matlab.ui.control.Label
        CheckPathButton               matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Hyperlink_2                   matlab.ui.control.Hyperlink
        Hyperlink                     matlab.ui.control.Hyperlink
        CreatedbyTylerSeawrightLabel  matlab.ui.control.Label
        V10Label                      matlab.ui.control.Label
        TolStackLabel                 matlab.ui.control.Label
        SolveButton                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function DisplayTextArea(app,msg)
            app.iTB = app.iTB + 1;
            app.UserTextArea.Value(app.iTB) = {msg};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function Button(app, event)
            TolStackSolve(STACKRANGE);
        end

        % Button pushed function: CheckPathButton
        function CheckPathButtonPushed(app, event)
            close all
            S = fetchstack(STACKRANGE);
            [~, ~, ~, ~, ~, S.Tn_list, ~] = solve_error_comp(S.R, zeros(size(S.R)), S.C, S.Cv);
            plot_coord2(COORD, S.Tn_list, S.Name)
        end

        % Callback function: not associated with a component
        function GeneratePlotsButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @Button, true);
            app.SolveButton.Text = 'Solve';
            app.TolStackLabel = uilabel(app.UIFigure);
            app.TolStackLabel.Text = 'TolStack';
            app.V10Label = uilabel(app.UIFigure);
            app.V10Label.Text = 'V1.0';
            app.CreatedbyTylerSeawrightLabel = uilabel(app.UIFigure);
            app.CreatedbyTylerSeawrightLabel.Text = 'Created by Tyler Seawright';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Text = 'Download Latest Version Here';
            app.Hyperlink_2 = uihyperlink(app.UIFigure);
            app.Hyperlink_2.Text = 'How to Use TolStack';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'TolStackIcon3.png');
            app.CheckPathButton = uibutton(app.UIFigure, 'push');
            app.CheckPathButton.ButtonPushedFcn = createCallbackFcn(app, @CheckPathButtonPushed, true);
            app.CheckPathButton.Text = 'Check Path';
            app.CS1Label = uilabel(app.UIFigure);
            app.CS1Label.Text = 'CS1';
            app.CS2Label = uilabel(app.UIFigure);
            app.CS2Label.Text = 'CS2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TolStack

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end